classdef APYV9WebApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        ScenarioTab                     matlab.ui.container.Tab
        LoadScenarioButton              matlab.ui.control.Button
        SaveScenarioButton              matlab.ui.control.Button
        UseDefaultsCheckBox             matlab.ui.control.CheckBox
        ScenarioLabelEditField          matlab.ui.control.EditField
        ScenarioLabel                   matlab.ui.control.Label
        InputsTab                       matlab.ui.container.Tab
        RegimenEffFullEditField         matlab.ui.control.NumericEditField
        RegimenEffFullEditFieldLabel    matlab.ui.control.Label
        RegimenADRstopEditField         matlab.ui.control.NumericEditField
        RegimenADRstopEditFieldLabel    matlab.ui.control.Label
        RegimenPCompleteEditField       matlab.ui.control.NumericEditField
        RegimenPCompleteEditFieldLabel  matlab.ui.control.Label
        PStartTPTEditField              matlab.ui.control.NumericEditField
        PStartTPTEditFieldLabel         matlab.ui.control.Label
        ScreenCoverageEditField         matlab.ui.control.NumericEditField
        ScreenCoverageEditFieldLabel    matlab.ui.control.Label
        ScreeningStrategyDropDown       matlab.ui.control.DropDown
        ScreeningStrategyDropDownLabel  matlab.ui.control.Label
        RegimenDropDown                 matlab.ui.control.DropDown
        RegimenDropDownLabel            matlab.ui.control.Label
        TestTypeDropDown                matlab.ui.control.DropDown
        TesttypeLabel                   matlab.ui.control.Label
        ActiveTBPrevalenceEditField     matlab.ui.control.NumericEditField
        ActiveTBprevalenceLabel         matlab.ui.control.Label
        LtbiPrevalenceEditField         matlab.ui.control.NumericEditField
        TBIprevalenceLabel              matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        NRepsEditField                  matlab.ui.control.NumericEditField
        NRepsEditFieldLabel             matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        ValidationMessagesTextArea      matlab.ui.control.TextArea
        ValidationMessagesLabel         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        TechnicalDetailsTextArea        matlab.ui.control.TextArea
        TechnicalDetailsLabel           matlab.ui.control.Label
        HeadlineResultsTable            matlab.ui.control.Table
        DownloadsTab                    matlab.ui.container.Tab
        DownloadsTable                  matlab.ui.control.Table
        ExportResultsButton             matlab.ui.control.Button
        EconomicsTab                    matlab.ui.container.Tab
        EconomicsSummaryTable           matlab.ui.control.Table
        EconomicsDetailsTextArea        matlab.ui.control.TextArea
        EconomicsDetailsTextAreaLabel   matlab.ui.control.Label
        RunEconomicsButton              matlab.ui.control.Button
        Regimen9HCostEditField          matlab.ui.control.NumericEditField
        Regimen9HCostEditField_2Label   matlab.ui.control.Label
        Regimen6HCostEditField          matlab.ui.control.NumericEditField
        Regimen6HCostLabel              matlab.ui.control.Label
        Regimen4RCostEditField          matlab.ui.control.NumericEditField
        Regimen4RCostEditField_2Label   matlab.ui.control.Label
        Regimen3HPCostEditField         matlab.ui.control.NumericEditField
        Regimen3HPCostEditFieldLabel    matlab.ui.control.Label
        Regimen3HRCostEditField         matlab.ui.control.NumericEditField
        Regimen3HRCostEditField_2Label  matlab.ui.control.Label
        ProgramRunningCostEditField     matlab.ui.control.NumericEditField
        ProgramRunningCostEditFieldLabel  matlab.ui.control.Label
        ProgramSetupCostEditField       matlab.ui.control.NumericEditField
        ProgramSetupCostEditFieldLabel  matlab.ui.control.Label
        ActiveTBDiseaseCostEditField    matlab.ui.control.NumericEditField
        ActiveTBDiseaseCostEditFieldLabel  matlab.ui.control.Label
        FalsePositiveIncrementalCostEditField  matlab.ui.control.NumericEditField
        FalsePositiveIncrementalCostEditFieldLabel  matlab.ui.control.Label
        TSTCostEditField                matlab.ui.control.NumericEditField
        TSTCostEditFieldLabel           matlab.ui.control.Label
        IGRACostEditField               matlab.ui.control.NumericEditField
        IGRACostEditFieldLabel          matlab.ui.control.Label
        ProgramCostBasisDropDown        matlab.ui.control.DropDown
        ProgramCostBasisDropDownLabel   matlab.ui.control.Label
        LoadKWAB150PresetButton         matlab.ui.control.Button
        SourceNotesTextArea             matlab.ui.control.TextArea
        SourceNotesTextAreaLabel        matlab.ui.control.Label
        LocationLabelEditField          matlab.ui.control.EditField
        LocationLabelEditFieldLabel     matlab.ui.control.Label
        PriceYearEditField              matlab.ui.control.NumericEditField
        PriceYearEditFieldLabel         matlab.ui.control.Label
        CurrencyCodeEditField           matlab.ui.control.EditField
        CurrencyCodeEditFieldLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        AppState % Description
    end


    methods (Access = private)
        function txt = localIssueText(app, issue)
            txt = '';
            if isstruct(issue)
                if isfield(issue, 'message') && ~isempty(issue.message)
                    txt = char(string(issue.message));
                elseif isfield(issue, 'text') && ~isempty(issue.text)
                    txt = char(string(issue.text));
                else
                    txt = strtrim(evalc('disp(issue)'));
                end
            else
                txt = char(string(issue));
            end
        end

        function InputValueChanged(app, event)
            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;

            if isfield(app.AppState.CurrentConfig, 'useDefaults')
                app.AppState.CurrentConfig.useDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            [app.AppState, ~] = app_input_changed_v9(app.AppState, uiState);

            refreshValidationDisplay(app);
            refreshResultsDisplay(app);
            refreshEconomicsDisplay(app);
        end
        function refreshValidationDisplay(app)
            displayModel = validation_report_to_display_v9(app.AppState.LastValidationReport);

            lines = {
                displayModel.summaryText
                sprintf('Valid: %d', displayModel.isValid)
                sprintf('Warnings present: %d', displayModel.hasWarnings)
                };

            if ~isempty(displayModel.errorRows)
                for i = 1:numel(displayModel.errorRows)
                    lines{end+1, 1} = ['ERROR: ' app.localIssueText(displayModel.errorRows(i))]; %#ok<AGROW>
                end
            end

            if ~isempty(displayModel.warningRows)
                for i = 1:numel(displayModel.warningRows)
                    lines{end+1, 1} = ['WARNING: ' app.localIssueText(displayModel.warningRows(i))]; %#ok<AGROW>
                end
            end

            if ~isempty(displayModel.infoRows)
                for i = 1:numel(displayModel.infoRows)
                    lines{end+1, 1} = ['INFO: ' app.localIssueText(displayModel.infoRows(i))]; %#ok<AGROW>
                end
            end

            app.ValidationMessagesTextArea.Value = lines;

        end

        function refreshResultsDisplay(app)
            if isempty(app.AppState) || isempty(app.AppState.LastBundle)
                app.setUITableData(app.HeadlineResultsTable, [], "No results available");
                app.TechnicalDetailsTextArea.Value = {'No technical details available.'};
                app.setUITableData(app.DownloadsTable, [], "No downloads available yet");
                return;
            end

            displayModel = results_bundle_to_display_v9(app.AppState.LastBundle);

            headlineTbl = [];
            if isstruct(displayModel) && isfield(displayModel, 'headline') ...
                    && isfield(displayModel.headline, 'available') && displayModel.headline.available

                if isfield(displayModel.headline, 'keyMetricsTable') && istable(displayModel.headline.keyMetricsTable)
                    headlineTbl = displayModel.headline.keyMetricsTable;
                elseif isfield(displayModel.headline, 'summaryTable') && istable(displayModel.headline.summaryTable)
                    headlineTbl = displayModel.headline.summaryTable;
                elseif isfield(displayModel.headline, 'keyMetricsRows') && ~isempty(displayModel.headline.keyMetricsRows)
                    try
                        headlineTbl = struct2table(displayModel.headline.keyMetricsRows, 'AsArray', true);
                    catch
                    end
                end
            end
            app.setUITableData(app.HeadlineResultsTable, headlineTbl, "No results available");

            technicalLines = {};
            if app.AppState.ResultsAreStale
                technicalLines{end+1,1} = 'Results are stale. Re-run the scenario to refresh outputs.';
            end

            if isstruct(displayModel) && isfield(displayModel, 'technical') ...
                    && isfield(displayModel.technical, 'available') && displayModel.technical.available

                technicalLines{end+1,1} = 'Technical details available.';

                if isfield(displayModel, 'metadata') && isstruct(displayModel.metadata)
                    if isfield(displayModel.metadata, 'contractVersion')
                        technicalLines{end+1,1} = ['Contract version: ' char(string(displayModel.metadata.contractVersion))];
                    end
                end

                if isfield(displayModel.technical, 'interfaceConfig') && ~isempty(displayModel.technical.interfaceConfig)
                    technicalLines{end+1,1} = 'Interface config summary:';
                    technicalLines{end+1,1} = strtrim(evalc('disp(displayModel.technical.interfaceConfig)'));
                end
            end

            if isempty(technicalLines)
                technicalLines = {'No technical details available.'};
            end

            app.TechnicalDetailsTextArea.Value = technicalLines;

            downloadsTbl = [];
            if isstruct(displayModel) && isfield(displayModel, 'downloads') ...
                    && isstruct(displayModel.downloads) ...
                    && isfield(displayModel.downloads, 'available') ...
                    && displayModel.downloads.available

                rows = struct([]);

                if isfield(displayModel.downloads, 'summaryCsv') && ~isempty(displayModel.downloads.summaryCsv)
                    rows(end+1).FileType = "Summary CSV"; %#ok<AGROW>
                    rows(end).Path = string(displayModel.downloads.summaryCsv);
                end

                if isfield(displayModel.downloads, 'keyMetricsCsv') && ~isempty(displayModel.downloads.keyMetricsCsv)
                    rows(end+1).FileType = "Key metrics CSV"; %#ok<AGROW>
                    rows(end).Path = string(displayModel.downloads.keyMetricsCsv);
                end

                if isfield(displayModel.downloads, 'payload') && ~isempty(displayModel.downloads.payload)
                    try
                        payloadFields = fieldnames(displayModel.downloads.payload);
                        for k = 1:numel(payloadFields)
                            val = displayModel.downloads.payload.(payloadFields{k});
                            if ischar(val) || isstring(val)
                                rows(end+1).FileType = string(payloadFields{k}); %#ok<AGROW>
                                rows(end).Path = string(val);
                            end
                        end
                    catch
                    end
                end

                if ~isempty(rows)
                    downloadsTbl = struct2table(rows);
                end
            end

            app.setUITableData(app.DownloadsTable, downloadsTbl, "No downloads available yet");

        end


        function setUITableData(app, uiTable, tbl, placeholderText)

            if nargin < 4 || isempty(placeholderText)
                placeholderText = "No data available";
            end

            if istable(tbl) && ~isempty(tbl)
                uiTable.Data = tbl;
                try
                    uiTable.ColumnName = tbl.Properties.VariableNames;
                catch
                end
            else
                uiTable.Data = table(string(placeholderText), 'VariableNames', {'Status'});
                uiTable.ColumnName = {'Status'};
            end

        end

        function EconomicsInputValueChanged(app, event)
            econUiState = collectEconomicsUIState(app);
            [app.AppState, ~] = app_economics_input_changed_v9(app.AppState, econUiState);
            refreshEconomicsDisplay(app);
        end


        function econUiState = collectEconomicsUIState(app)
            econUiState = struct();
            econUiState.fieldValues = struct();

            econUiState.fieldValues.CurrencyCodeEditField = app.CurrencyCodeEditField.Value;
            econUiState.fieldValues.PriceYearEditField = app.PriceYearEditField.Value;
            econUiState.fieldValues.LocationLabelEditField = app.LocationLabelEditField.Value;
            if iscell(app.SourceNotesTextArea.Value)
                econUiState.fieldValues.SourceNotesTextArea = strjoin(app.SourceNotesTextArea.Value, newline);
            else
                econUiState.fieldValues.SourceNotesTextArea = char(string(app.SourceNotesTextArea.Value));
            end
            val = app.ProgramCostBasisDropDown.Value;
            if strcmp(char(string(val)), 'unspecified')
                val = '';
            end
            econUiState.fieldValues.ProgramCostBasisDropDown = char(string(val));
            econUiState.fieldValues.IGRACostEditField = app.IGRACostEditField.Value;
            econUiState.fieldValues.TSTCostEditField = app.TSTCostEditField.Value;
            econUiState.fieldValues.Regimen3HPCostEditField = app.Regimen3HPCostEditField.Value;
            econUiState.fieldValues.Regimen4RCostEditField = app.Regimen4RCostEditField.Value;
            econUiState.fieldValues.Regimen3HRCostEditField = app.Regimen3HRCostEditField.Value;
            econUiState.fieldValues.Regimen6HCostEditField = app.Regimen6HCostEditField.Value;
            econUiState.fieldValues.Regimen9HCostEditField = app.Regimen9HCostEditField.Value;
            econUiState.fieldValues.FalsePositiveIncrementalCostEditField = app.FalsePositiveIncrementalCostEditField.Value;
            econUiState.fieldValues.ActiveTBDiseaseCostEditField = app.ActiveTBDiseaseCostEditField.Value;
            econUiState.fieldValues.ProgramSetupCostEditField = app.ProgramSetupCostEditField.Value;
            econUiState.fieldValues.ProgramRunningCostEditField = app.ProgramRunningCostEditField.Value;
        end


        function refreshEconomicsDisplay(app)
            % Default placeholders
            app.EconomicsSummaryTable.Data = table("No economics results available", 'VariableNames', {'Status'});
            app.EconomicsSummaryTable.ColumnName = {'Status'};
            app.EconomicsDetailsTextArea.Value = {'Economics not yet run.'};

            % Show validation / stale state even before a run
            lines = {};

            if isfield(app.AppState, 'EconomicsAreStale') && app.AppState.EconomicsAreStale
                lines{end+1,1} = 'Economics results are stale. Re-run economics to refresh outputs.'; %#ok<AGROW>
            end

            if isfield(app.AppState, 'LastEconomicsValidationReport') && ~isempty(app.AppState.LastEconomicsValidationReport)
                report = app.AppState.LastEconomicsValidationReport;

                if isfield(report, 'errors') && ~isempty(report.errors)
                    for i = 1:numel(report.errors)
                        lines{end+1,1} = ['ERROR: ' app.localIssueText(report.errors(i))]; %#ok<AGROW>
                    end
                end

                if isfield(report, 'warnings') && ~isempty(report.warnings)
                    for i = 1:numel(report.warnings)
                        lines{end+1,1} = ['WARNING: ' app.localIssueText(report.warnings(i))]; %#ok<AGROW>
                    end
                end

            end

            % If economics exists, display it
            if isfield(app.AppState, 'LastBundle') && ~isempty(app.AppState.LastBundle)
                displayModel = results_bundle_to_display_v9(app.AppState.LastBundle);

                if isfield(displayModel, 'economics') && isstruct(displayModel.economics) ...
                        && isfield(displayModel.economics, 'available') && displayModel.economics.available

                    if isfield(displayModel.economics, 'summaryTable') && istable(displayModel.economics.summaryTable) ...
                            && ~isempty(displayModel.economics.summaryTable)
                        app.EconomicsSummaryTable.Data = displayModel.economics.summaryTable;
                        try
                            app.EconomicsSummaryTable.ColumnName = displayModel.economics.summaryTable.Properties.VariableNames;
                        catch
                        end
                    elseif isfield(displayModel.economics, 'summaryRows') && ~isempty(displayModel.economics.summaryRows)
                        try
                            tbl = struct2table(displayModel.economics.summaryRows, 'AsArray', true);
                            app.EconomicsSummaryTable.Data = tbl;
                            app.EconomicsSummaryTable.ColumnName = tbl.Properties.VariableNames;
                        catch
                        end
                    end

                    lines = {};
                    lines{end+1,1} = 'Economics results available.'; %#ok<AGROW>

                    if isfield(displayModel.economics, 'metadata') && ~isempty(displayModel.economics.metadata)
                        md = displayModel.economics.metadata;
                        if isfield(md, 'currencyCode') && ~isempty(md.currencyCode)
                            lines{end+1,1} = ['Currency: ' char(string(md.currencyCode))]; %#ok<AGROW>
                        end
                        if isfield(md, 'priceYear') && ~isempty(md.priceYear)
                            lines{end+1,1} = ['Price year: ' char(string(md.priceYear))]; %#ok<AGROW>
                        end
                        if isfield(md, 'locationLabel') && ~isempty(md.locationLabel)
                            lines{end+1,1} = ['Location: ' char(string(md.locationLabel))]; %#ok<AGROW>
                        end
                    end

                    if isfield(displayModel.economics, 'status') && ~isempty(displayModel.economics.status)
                        lines{end+1,1} = 'Status:'; %#ok<AGROW>
                        lines{end+1,1} = strtrim(evalc('disp(displayModel.economics.status)')); %#ok<AGROW>
                    end
                end
            end

            if isempty(lines)
                lines = {'Economics not yet run.'};
            end

            app.EconomicsDetailsTextArea.Value = lines;

        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            drawnow;
            app.UIFigure.WindowState = 'maximized';

            [app.AppState, ~, ~] = app_startup_v9();
            app = app_apply_ui_state_v9(app, app.AppState.CurrentUIState, app.AppState.Schema);

            if isfield(app.AppState.CurrentConfig, 'useDefaults')
                app.UseDefaultsCheckBox.Value = logical(app.AppState.CurrentConfig.useDefaults);
            end

            refreshValidationDisplay(app);
            refreshResultsDisplay(app);
            econUiState = economics_config_to_ui_state_v9(app.AppState.CurrentEconomicsConfig);
            app = app_apply_economics_ui_state_v9(app, econUiState);
            refreshEconomicsDisplay(app);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;

            if isfield(app.AppState.CurrentConfig, 'useDefaults')
                app.AppState.CurrentConfig.useDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            [app.AppState, ~, runError] = app_run_v9(app.AppState, uiState);

            refreshValidationDisplay(app);

            if ~isempty(runError)
                app.TechnicalDetailsTextArea.Value = { ...
                    'Run failed.'; ...
                    runError};
                return;
            end

            refreshResultsDisplay(app);

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)

            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;

            if isfield(app.AppState.CurrentConfig, 'useDefaults')
                app.AppState.CurrentConfig.useDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            [app.AppState, ~, validationError] = app_validate_v9(app.AppState, uiState);

            refreshValidationDisplay(app);

            if ~isempty(validationError)
                app.ValidationMessagesTextArea.Value = { ...
                    'Validation failed.'; ...
                    validationError};
            end

        end

        % Button pushed function: LoadScenarioButton
        function LoadScenarioButtonPushed(app, event)

            [fileName, filePath] = uigetfile('*.json', 'Load scenario');
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end

            fullName = fullfile(filePath, fileName);
            [app.AppState, ~, ~, ~] = app_load_scenario_v9(app.AppState, fullName);

            app = app_apply_ui_state_v9(app, app.AppState.CurrentUIState, app.AppState.Schema);

            if isfield(app.AppState.CurrentConfig, 'useDefaults')
                app.UseDefaultsCheckBox.Value = logical(app.AppState.CurrentConfig.useDefaults);
            end

            refreshValidationDisplay(app);
            refreshResultsDisplay(app);
            econUiState = economics_config_to_ui_state_v9(app.AppState.CurrentEconomicsConfig);
            app = app_apply_economics_ui_state_v9(app, econUiState);
            refreshEconomicsDisplay(app);


        end

        % Button pushed function: SaveScenarioButton
        function SaveScenarioButtonPushed(app, event)

            uiState = app_collect_ui_state_v9(app, app.AppState.Schema);
            app.AppState.CurrentUIState = uiState;
            app.AppState.CurrentConfig = ui_state_to_config_v9(uiState, app.AppState.Schema, app.AppState.CurrentConfig);

            if isfield(app.AppState.CurrentConfig, 'useDefaults')
                app.AppState.CurrentConfig.useDefaults = logical(app.UseDefaultsCheckBox.Value);
            end

            defaultFile = 'scenario_v9.json';
            if isfield(app.AppState.CurrentConfig, 'scenarioLabel') && ~isempty(app.AppState.CurrentConfig.scenarioLabel)
                defaultFile = [regexprep(char(string(app.AppState.CurrentConfig.scenarioLabel)), '[^a-zA-Z0-9_-]', '_') '.json'];
            end

            [fileName, filePath] = uiputfile('*.json', 'Save scenario', defaultFile);
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end

            fullName = fullfile(filePath, fileName);
            [app.AppState, ~, errMsg] = app_save_scenario_v9(app.AppState, fullName);

            if ~isempty(errMsg)
                app.ValidationMessagesTextArea.Value = { ...
                    'Save failed.'; ...
                    errMsg};
                return;
            end

            refreshValidationDisplay(app);

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            answer = inputdlg('Export base name:', 'Export results', 1, {'tb_screening_v9'});
            if isempty(answer)
                return;
            end

            baseName = strtrim(answer{1});
            if isempty(baseName)
                baseName = 'tb_screening_v9';
            end

            [app.AppState, ~, errMsg] = app_export_v9(app.AppState, baseName);

            if ~isempty(errMsg)
                app.TechnicalDetailsTextArea.Value = { ...
                    'Export failed.'; ...
                    errMsg};
                return;
            end
            refreshResultsDisplay(app);
        end

        % Value changed function: LtbiPrevalenceEditField
        function LtbiPrevalenceEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: ActiveTBPrevalenceEditField
        function ActiveTBPrevalenceEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: TestTypeDropDown
        function TestTypeDropDownValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: RegimenDropDown
        function RegimenRegimenDropDownValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: ScreeningStrategyDropDown
        function ScreeningStrategyDropDownValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: ScreenCoverageEditField
        function ScreenCoverageEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: PStartTPTEditField
        function PStartTPTEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: RegimenPCompleteEditField
        function RegimenPCompleteEditFieldValueChanged(app, event)
            InputValueChanged(app, event);
        end

        % Value changed function: RegimenADRstopEditField
        function RegimenADRstopEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: RegimenEffFullEditField
        function RegimenEffFullEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: NRepsEditField
        function NRepsEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: ScenarioLabelEditField
        function ScenarioLabelEditFieldValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: UseDefaultsCheckBox
        function UseDefaultsCheckBoxValueChanged(app, event)
            InputValueChanged(app, event);

        end

        % Value changed function: CurrencyCodeEditField
        function CurrencyCodeEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: PriceYearEditField
        function PriceYearEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: LocationLabelEditField
        function LocationLabelEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: ProgramCostBasisDropDown
        function ProgramCostBasisDropDownValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Callback function: not associated with a component
        function IGRACostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Callback function: not associated with a component
        function TSTCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Button pushed function: LoadKWAB150PresetButton
        function LoadKWAB150PresetButtonPushed(app, event)
            [app.AppState, econUiState, ~, errMsg] = app_load_economics_preset_v9(app.AppState, 'KWAB150');

            if ~isempty(errMsg)
                app.EconomicsDetailsTextArea.Value = {'Failed to load KWAB150 preset.', errMsg};
                return;
            end

            app = app_apply_economics_ui_state_v9(app, econUiState);
            refreshEconomicsDisplay(app);
        end

        % Callback function: not associated with a component
        function FalsePositiveIncrementalCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Callback function: not associated with a component
        function ActiveTBDiseaseCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Callback function: not associated with a component
        function ProgramSetupCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Callback function: not associated with a component
        function ProgramRunningCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: Regimen3HRCostEditField
        function Regimen3HRCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: Regimen3HPCostEditField
        function Regimen3HPCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: Regimen4RCostEditField
        function Regimen4RCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: Regimen6HCostEditField
        function Regimen6HCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: Regimen9HCostEditField
        function Regimen9HCostEditFieldValueChanged(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: ProgramRunningCostEditField
        function ProgramRunningCostEditFieldValueChanged2(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: ProgramSetupCostEditField
        function ProgramSetupCostEditFieldValueChanged2(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: ActiveTBDiseaseCostEditField
        function ActiveTBDiseaseCostEditFieldValueChanged2(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: FalsePositiveIncrementalCostEditField
        function FalsePositiveIncrementalCostEditFieldValueChanged2(app, event)
            EconomicsInputValueChanged(app, event);
        end

        % Value changed function: TSTCostEditField
        function TSTCostEditFieldValueChanged2(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Value changed function: IGRACostEditField
        function IGRACostEditFieldValueChanged2(app, event)
            EconomicsInputValueChanged(app, event);

        end

        % Callback function: not associated with a component
        function RunEconomicsButtonPushed2(app, event)
            econUiState = collectEconomicsUIState(app);
            [app.AppState, ~, errMsg] = app_run_economics_v9(app.AppState, econUiState);

            if ~isempty(errMsg)
                app.EconomicsDetailsTextArea.Value = {'Economics run failed.', errMsg};
                return;
            end

            refreshResultsDisplay(app);
            refreshEconomicsDisplay(app);
        end

        % Button pushed function: RunEconomicsButton
        function RunEconomicsButtonPushed(app, event)
              econUiState = collectEconomicsUIState(app);
            [app.AppState, ~, errMsg] = app_run_economics_v9(app.AppState, econUiState);

            if ~isempty(errMsg)
                app.EconomicsDetailsTextArea.Value = {'Economics run failed.', errMsg};
                return;
            end

            refreshResultsDisplay(app);
            refreshEconomicsDisplay(app);
        end

        % Value changed function: SourceNotesTextArea
        function SourceNotesTextAreaValueChanged(app, event)
             EconomicsInputValueChanged(app, event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.ScenarioTab = uitab(app.MainTabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ScenarioLabel = uilabel(app.ScenarioTab);
            app.ScenarioLabel.Text = 'Scenario Label';
            app.ScenarioLabelEditField = uieditfield(app.ScenarioTab, 'text');
            app.ScenarioLabelEditField.ValueChangedFcn = createCallbackFcn(app, @ScenarioLabelEditFieldValueChanged, true);
            app.ScenarioLabelEditField.Value = 'DEFAULT';
            app.UseDefaultsCheckBox = uicheckbox(app.ScenarioTab);
            app.UseDefaultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDefaultsCheckBoxValueChanged, true);
            app.UseDefaultsCheckBox.Text = 'Use Defaults';
            app.SaveScenarioButton = uibutton(app.ScenarioTab, 'push');
            app.SaveScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScenarioButtonPushed, true);
            app.SaveScenarioButton.Text = 'Save Scenario';
            app.LoadScenarioButton = uibutton(app.ScenarioTab, 'push');
            app.LoadScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadScenarioButtonPushed, true);
            app.LoadScenarioButton.Text = 'Load Scenario';
            app.InputsTab = uitab(app.MainTabGroup);
            app.InputsTab.Title = 'Inputs';
            app.TBIprevalenceLabel = uilabel(app.InputsTab);
            app.TBIprevalenceLabel.Text = 'TBI prevalence';
            app.LtbiPrevalenceEditField = uieditfield(app.InputsTab, 'numeric');
            app.LtbiPrevalenceEditField.ValueChangedFcn = createCallbackFcn(app, @LtbiPrevalenceEditFieldValueChanged, true);
            app.ActiveTBprevalenceLabel = uilabel(app.InputsTab);
            app.ActiveTBprevalenceLabel.Text = 'Active TB prevalence';
            app.ActiveTBPrevalenceEditField = uieditfield(app.InputsTab, 'numeric');
            app.ActiveTBPrevalenceEditField.ValueChangedFcn = createCallbackFcn(app, @ActiveTBPrevalenceEditFieldValueChanged, true);
            app.TesttypeLabel = uilabel(app.InputsTab);
            app.TesttypeLabel.Text = 'Test Type';
            app.TestTypeDropDown = uidropdown(app.InputsTab);
            app.TestTypeDropDown.Items = {'IGRA', 'TST'};
            app.TestTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TestTypeDropDownValueChanged, true);
            app.TestTypeDropDown.Value = 'IGRA';
            app.RegimenDropDownLabel = uilabel(app.InputsTab);
            app.RegimenDropDownLabel.Text = 'Regimen';
            app.RegimenDropDown = uidropdown(app.InputsTab);
            app.RegimenDropDown.Items = {'3HP', '4R', '3HR', '6H', '9H'};
            app.RegimenDropDown.ValueChangedFcn = createCallbackFcn(app, @RegimenRegimenDropDownValueChanged, true);
            app.RegimenDropDown.Value = '3HP';
            app.ScreeningStrategyDropDownLabel = uilabel(app.InputsTab);
            app.ScreeningStrategyDropDownLabel.Text = 'Screening Strategy';
            app.ScreeningStrategyDropDown = uidropdown(app.InputsTab);
            app.ScreeningStrategyDropDown.Items = {'random', 'ltbi', 'cure', 'prevent'};
            app.ScreeningStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @ScreeningStrategyDropDownValueChanged, true);
            app.ScreeningStrategyDropDown.Value = 'random';
            app.ScreenCoverageEditFieldLabel = uilabel(app.InputsTab);
            app.ScreenCoverageEditFieldLabel.Text = 'Screen Coverage';
            app.ScreenCoverageEditField = uieditfield(app.InputsTab, 'numeric');
            app.ScreenCoverageEditField.ValueChangedFcn = createCallbackFcn(app, @ScreenCoverageEditFieldValueChanged, true);
            app.PStartTPTEditFieldLabel = uilabel(app.InputsTab);
            app.PStartTPTEditFieldLabel.Text = 'P Start TPT';
            app.PStartTPTEditField = uieditfield(app.InputsTab, 'numeric');
            app.PStartTPTEditField.ValueChangedFcn = createCallbackFcn(app, @PStartTPTEditFieldValueChanged, true);
            app.RegimenPCompleteEditFieldLabel = uilabel(app.InputsTab);
            app.RegimenPCompleteEditFieldLabel.Text = 'RegimenPComplete';
            app.RegimenPCompleteEditField = uieditfield(app.InputsTab, 'numeric');
            app.RegimenPCompleteEditField.ValueChangedFcn = createCallbackFcn(app, @RegimenPCompleteEditFieldValueChanged, true);
            app.RegimenADRstopEditFieldLabel = uilabel(app.InputsTab);
            app.RegimenADRstopEditFieldLabel.Text = 'RegimenADRstop';
            app.RegimenADRstopEditField = uieditfield(app.InputsTab, 'numeric');
            app.RegimenADRstopEditField.ValueChangedFcn = createCallbackFcn(app, @RegimenADRstopEditFieldValueChanged, true);
            app.RegimenEffFullEditFieldLabel = uilabel(app.InputsTab);
            app.RegimenEffFullEditFieldLabel.Text = 'RegimenEffFull';
            app.RegimenEffFullEditField = uieditfield(app.InputsTab, 'numeric');
            app.RegimenEffFullEditField.ValueChangedFcn = createCallbackFcn(app, @RegimenEffFullEditFieldValueChanged, true);
            app.AdvancedTab = uitab(app.MainTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.NRepsEditFieldLabel = uilabel(app.AdvancedTab);
            app.NRepsEditFieldLabel.Text = 'NReps';
            app.NRepsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.NRepsEditField.ValueChangedFcn = createCallbackFcn(app, @NRepsEditFieldValueChanged, true);
            app.RunTab = uitab(app.MainTabGroup);
            app.RunTab.Title = 'Run';
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Scenario';
            app.ValidateButton = uibutton(app.RunTab, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate Inputs';
            app.ValidationMessagesLabel = uilabel(app.RunTab);
            app.ValidationMessagesLabel.Text = 'Validation Messages';
            app.ValidationMessagesTextArea = uitextarea(app.RunTab);
            app.ResultsTab = uitab(app.MainTabGroup);
            app.ResultsTab.Title = 'Results';
            app.HeadlineResultsTable = uitable(app.ResultsTab);
            app.HeadlineResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.HeadlineResultsTable.RowName = {};
            app.TechnicalDetailsLabel = uilabel(app.ResultsTab);
            app.TechnicalDetailsLabel.Text = 'Technical Details';
            app.TechnicalDetailsTextArea = uitextarea(app.ResultsTab);
            app.DownloadsTab = uitab(app.MainTabGroup);
            app.DownloadsTab.Title = 'Downloads';
            app.ExportResultsButton = uibutton(app.DownloadsTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.DownloadsTable = uitable(app.DownloadsTab);
            app.DownloadsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DownloadsTable.RowName = {};
            app.EconomicsTab = uitab(app.MainTabGroup);
            app.EconomicsTab.Title = 'Economics';
            app.CurrencyCodeEditFieldLabel = uilabel(app.EconomicsTab);
            app.CurrencyCodeEditFieldLabel.Text = 'Currency Code';
            app.CurrencyCodeEditField = uieditfield(app.EconomicsTab, 'text');
            app.CurrencyCodeEditField.ValueChangedFcn = createCallbackFcn(app, @CurrencyCodeEditFieldValueChanged, true);
            app.PriceYearEditFieldLabel = uilabel(app.EconomicsTab);
            app.PriceYearEditFieldLabel.Text = 'PriceYear';
            app.PriceYearEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.PriceYearEditField.AllowEmpty = 'on';
            app.PriceYearEditField.ValueChangedFcn = createCallbackFcn(app, @PriceYearEditFieldValueChanged, true);
            app.LocationLabelEditFieldLabel = uilabel(app.EconomicsTab);
            app.LocationLabelEditFieldLabel.Text = 'LocationLabel';
            app.LocationLabelEditField = uieditfield(app.EconomicsTab, 'text');
            app.LocationLabelEditField.ValueChangedFcn = createCallbackFcn(app, @LocationLabelEditFieldValueChanged, true);
            app.SourceNotesTextAreaLabel = uilabel(app.EconomicsTab);
            app.SourceNotesTextAreaLabel.Text = 'SourceNotes';
            app.SourceNotesTextArea = uitextarea(app.EconomicsTab);
            app.SourceNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @SourceNotesTextAreaValueChanged, true);
            app.LoadKWAB150PresetButton = uibutton(app.EconomicsTab, 'push');
            app.LoadKWAB150PresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadKWAB150PresetButtonPushed, true);
            app.LoadKWAB150PresetButton.Text = 'LoadKWAB150Preset';
            app.ProgramCostBasisDropDownLabel = uilabel(app.EconomicsTab);
            app.ProgramCostBasisDropDownLabel.Text = 'ProgramCostBasis';
            app.ProgramCostBasisDropDown = uidropdown(app.EconomicsTab);
            app.ProgramCostBasisDropDown.Items = {'unspecified', 'total', 'annual', 'per_screen_window'};
            app.ProgramCostBasisDropDown.ValueChangedFcn = createCallbackFcn(app, @ProgramCostBasisDropDownValueChanged, true);
            app.ProgramCostBasisDropDown.Value = 'unspecified';
            app.IGRACostEditFieldLabel = uilabel(app.EconomicsTab);
            app.IGRACostEditFieldLabel.Text = 'IGRACost';
            app.IGRACostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.IGRACostEditField.AllowEmpty = 'on';
            app.IGRACostEditField.ValueChangedFcn = createCallbackFcn(app, @IGRACostEditFieldValueChanged2, true);
            app.TSTCostEditFieldLabel = uilabel(app.EconomicsTab);
            app.TSTCostEditFieldLabel.Text = 'TSTCost';
            app.TSTCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.TSTCostEditField.AllowEmpty = 'on';
            app.TSTCostEditField.ValueChangedFcn = createCallbackFcn(app, @TSTCostEditFieldValueChanged2, true);
            app.FalsePositiveIncrementalCostEditFieldLabel = uilabel(app.EconomicsTab);
            app.FalsePositiveIncrementalCostEditFieldLabel.Text = 'FalsePositiveIncrementalCostEditField';
            app.FalsePositiveIncrementalCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.FalsePositiveIncrementalCostEditField.AllowEmpty = 'on';
            app.FalsePositiveIncrementalCostEditField.ValueChangedFcn = createCallbackFcn(app, @FalsePositiveIncrementalCostEditFieldValueChanged2, true);
            app.ActiveTBDiseaseCostEditFieldLabel = uilabel(app.EconomicsTab);
            app.ActiveTBDiseaseCostEditFieldLabel.Text = 'ActiveTBDiseaseCost';
            app.ActiveTBDiseaseCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.ActiveTBDiseaseCostEditField.AllowEmpty = 'on';
            app.ActiveTBDiseaseCostEditField.ValueChangedFcn = createCallbackFcn(app, @ActiveTBDiseaseCostEditFieldValueChanged2, true);
            app.ProgramSetupCostEditFieldLabel = uilabel(app.EconomicsTab);
            app.ProgramSetupCostEditFieldLabel.Text = 'ProgramSetupCost';
            app.ProgramSetupCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.ProgramSetupCostEditField.AllowEmpty = 'on';
            app.ProgramSetupCostEditField.ValueChangedFcn = createCallbackFcn(app, @ProgramSetupCostEditFieldValueChanged2, true);
            app.ProgramRunningCostEditFieldLabel = uilabel(app.EconomicsTab);
            app.ProgramRunningCostEditFieldLabel.Text = 'ProgramRunningCost';
            app.ProgramRunningCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.ProgramRunningCostEditField.AllowEmpty = 'on';
            app.ProgramRunningCostEditField.ValueChangedFcn = createCallbackFcn(app, @ProgramRunningCostEditFieldValueChanged2, true);
            app.Regimen3HRCostEditField_2Label = uilabel(app.EconomicsTab);
            app.Regimen3HRCostEditField_2Label.Text = 'Regimen3HRCost';
            app.Regimen3HRCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.Regimen3HRCostEditField.AllowEmpty = 'on';
            app.Regimen3HRCostEditField.ValueChangedFcn = createCallbackFcn(app, @Regimen3HRCostEditFieldValueChanged, true);
            app.Regimen3HPCostEditFieldLabel = uilabel(app.EconomicsTab);
            app.Regimen3HPCostEditFieldLabel.Text = 'Regimen3HPCost';
            app.Regimen3HPCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.Regimen3HPCostEditField.AllowEmpty = 'on';
            app.Regimen3HPCostEditField.ValueChangedFcn = createCallbackFcn(app, @Regimen3HPCostEditFieldValueChanged, true);
            app.Regimen4RCostEditField_2Label = uilabel(app.EconomicsTab);
            app.Regimen4RCostEditField_2Label.Text = 'Regimen4RCost';
            app.Regimen4RCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.Regimen4RCostEditField.AllowEmpty = 'on';
            app.Regimen4RCostEditField.ValueChangedFcn = createCallbackFcn(app, @Regimen4RCostEditFieldValueChanged, true);
            app.Regimen6HCostLabel = uilabel(app.EconomicsTab);
            app.Regimen6HCostLabel.Text = 'Regimen6HCost';
            app.Regimen6HCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.Regimen6HCostEditField.AllowEmpty = 'on';
            app.Regimen6HCostEditField.ValueChangedFcn = createCallbackFcn(app, @Regimen6HCostEditFieldValueChanged, true);
            app.Regimen9HCostEditField_2Label = uilabel(app.EconomicsTab);
            app.Regimen9HCostEditField_2Label.Text = 'Regimen9HCost';
            app.Regimen9HCostEditField = uieditfield(app.EconomicsTab, 'numeric');
            app.Regimen9HCostEditField.AllowEmpty = 'on';
            app.Regimen9HCostEditField.ValueChangedFcn = createCallbackFcn(app, @Regimen9HCostEditFieldValueChanged, true);
            app.RunEconomicsButton = uibutton(app.EconomicsTab, 'push');
            app.RunEconomicsButton.ButtonPushedFcn = createCallbackFcn(app, @RunEconomicsButtonPushed, true);
            app.RunEconomicsButton.Text = 'RunEconomics';
            app.EconomicsDetailsTextAreaLabel = uilabel(app.EconomicsTab);
            app.EconomicsDetailsTextAreaLabel.Text = 'EconomicsDetails';
            app.EconomicsDetailsTextArea = uitextarea(app.EconomicsTab);
            app.EconomicsSummaryTable = uitable(app.EconomicsTab);
            app.EconomicsSummaryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EconomicsSummaryTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APYV9WebApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end